classdef manualCurateCellMOD3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        lastFrameupButton       matlab.ui.control.Button
        nextFramedownButton     matlab.ui.control.Button
        loadListButton          matlab.ui.control.Button
        exportListButton        matlab.ui.control.Button
        undocButton             matlab.ui.control.Button
        Slider                  matlab.ui.control.Slider
        SliderLabel             matlab.ui.control.Label
        spiralaButton           matlab.ui.control.Button
        previouscellleftButton  matlab.ui.control.Button
        nextcellrightButton     matlab.ui.control.Button
        loadDataButton          matlab.ui.control.Button
        UIAxes3                 matlab.ui.control.UIAxes
        UIAxes2                 matlab.ui.control.UIAxes
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        tracePhase
        cells % Description
        spirals
        totalCell
        pwAll
        frameID
        totalFrame
        frame
        frameLastCell
        spiralCrit
        icell
        indx
        spiralList 
        t % title object
        key
    end
    
    methods (Access = private)
        
        function [cx,cy,ph,circPhase] = getPhaseValue(app)
                currentCell = app.cells(:,:,app.icell);
                px = 21; py = 21;
                r = 10;
                th = 1:36:360; 
                cx = round(r*cosd(th)+px);
                cy = round(r*sind(th)+py); 
                ph = currentCell(sub2ind(size(currentCell),cy, cx));
                phDiff = angdiff(ph);
                circPhase(1) = ph(1);
                for i = 2:numel(ph)                
                    circPhase(i) = [circPhase(i-1)+phDiff(i-1)];
                end
                circPhase = circPhase-circPhase(1);
                ph3 = abs(circPhase);  
                spiralRange = linspace(0,2*pi,5);
                [N,edges] = histcounts(ph3,spiralRange);
                AngleRange = ph3(end);
                app.spiralCrit = 0;
                if AngleRange>5 & all(N)
                    app.spiralCrit = 1;
                end
        end
        
        function  updateFrame(app) 
                    app.icell = find(app.indx,1,'first');
                    imagesc(app.UIAxes,squeeze(app.cells(:,:,app.icell)));
                    colormap(app.UIAxes,hsv);
                    caxis(app.UIAxes,[-pi,pi])
                    axis(app.UIAxes, 'tight','equal','on');
                    app.t = title(app.UIAxes, ['cell ',num2str(app.icell)  ' /' num2str(app.totalCell)],'visible','on','Color','k'); 
                    app.t.FontSize = 16;
                    if app.spiralList(app.icell)
                        app.t.Color = 'r';
                    end
                    imagesc(app.UIAxes2,squeeze(app.tracePhase(:,:,app.frame)));
                    caxis(app.UIAxes2,[-pi,pi])
                    colormap(app.UIAxes2,hsv);
                    axis(app.UIAxes2, 'tight','equal','on');
                    title(app.UIAxes2, ['frame ' num2str(app.frame) ' /' num2str(app.totalFrame)], 'visible','on');
                    
                    hold(app.UIAxes2,'on')
                    points = app.pwAll(app.indx,:);
                    scatter(app.UIAxes2,points(:,1),points(:,2),'w','filled');
                    hold(app.UIAxes2,'on')
                    scatter(app.UIAxes2,app.pwAll(app.icell,1),app.pwAll(app.icell,2),'k','filled')
                    
                    if not(isempty(app.spirals))
                        hold(app.UIAxes2,'on')
                        indx1 = (app.spirals(:,3) == app.frame);
                        spiralsFrame = app.spirals(indx1,:);
                        scatter(app.UIAxes2,spiralsFrame(:,1),spiralsFrame(:,2),'r','filled');
                    end              
        end
        
        
        function updateCell(app)
                imagesc(app.UIAxes,squeeze(app.cells(:,:,app.icell)));
                colormap(app.UIAxes,hsv);
                caxis(app.UIAxes,[-pi,pi])
                axis(app.UIAxes, 'tight','equal','on');
                app.t = title(app.UIAxes, ['cell ',num2str(app.icell)  ' /' num2str(app.totalCell)],'visible','on','Color','k'); 
                app.t.FontSize = 16;
                if app.spiralList(app.icell)
                    app.t.Color = 'r';
                end
                
                hold(app.UIAxes2,'on')
                app.indx = (app.pwAll(:,end) == app.frame);
                points = app.pwAll(app.indx,:);
                scatter(app.UIAxes2,points(:,1),points(:,2),'w','filled');
                hold(app.UIAxes2,'on')
                scatter(app.UIAxes2,app.pwAll(app.icell,1),app.pwAll(app.icell,2),'k','filled')
                
                if not(isempty(app.spirals))
                    hold(app.UIAxes2,'on')
                    indx1 = (app.spirals(:,3) == app.frame);
                    spiralsFrame = app.spirals(indx1,:);
                    scatter(app.UIAxes2,spiralsFrame(:,1),spiralsFrame(:,2),'r','filled');
                end  
                %% draw phase value with 2 pixel radius
                [cx,cy,ph,circPhase] = getPhaseValue(app);
                scatter(app.UIAxes3,1:numel(circPhase),circPhase,12,'blue');
                axis(app.UIAxes3, 'tight','on');
                ylim(app.UIAxes3,[-2*pi,2*pi]);
%                 hold(app.UIAxes3,'on');
%                 scatter(app.UIAxes3,1:numel(ph),ph,8,'red');
                hold(app.UIAxes3,'off');
                title(app.UIAxes3,['spiral ' num2str(app.spiralCrit)])
                
                hold(app.UIAxes,'on');
                scatter(app.UIAxes,cx,cy,'w','filled')
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: loadDataButton
        function loadDataButtonPushed(app, event)
            [file,path] = uigetfile('*.mat'); %open a mat file
            load(fullfile(path, file));
            app.cells = cells;
            app.tracePhase = tracePhase;
            app.pwAll = pwAllEpoch;
            app.frameID = frameID;
            app.spirals = [];
            app.pwAll(:,end) = app.pwAll(:,end)-app.frameID(1)+1;
            
            app.totalCell = size(app.cells,3);
            imagesc(app.UIAxes,squeeze(app.cells(:,:,1)));
            colormap(app.UIAxes,hsv);
            caxis(app.UIAxes,[-pi,pi])
            axis(app.UIAxes, 'tight','equal','on');
            title(app.UIAxes, 'cell 1','visible','on');
            app.frame = app.pwAll(1,end);
            app.icell = 1;
            app.spiralList  = zeros(app.totalCell,1);
            %% draw phase value with 2 pixel radius
            [cx,cy,ph,circPhase] = getPhaseValue(app);
            scatter(app.UIAxes3,1:numel(circPhase),circPhase,11,'blue');
            axis(app.UIAxes3, 'tight','on');
            ylim(app.UIAxes3,[-2*pi,2*pi]);
            hold(app.UIAxes3,'on');
            scatter(app.UIAxes3,1:numel(ph),ph,8,'red');
            hold(app.UIAxes3,'off');
            
            hold(app.UIAxes,'on');
            scatter(app.UIAxes,cx,cy,'w','filled')
                       
            app.totalFrame = size(app.tracePhase,3);
            imagesc(app.UIAxes2,squeeze(app.tracePhase(:,:,app.frame)));
            colormap(app.UIAxes2,hsv);
            axis(app.UIAxes2, 'tight','equal','on');
            title(app.UIAxes2,  ['frame ' num2str(app.frame) ' /' num2str(app.totalFrame)],'visible','on');
            app.Slider.Limits = [1, app.totalFrame];
            tickLabelN = floor(size(app.tracePhase,1)/5);
            tickLabel = linspace(1,tickLabelN*5+1,6);
            app.Slider.MajorTicks = tickLabel;
            app.Slider.MajorTickLabels = string(tickLabel);           
        end

        % Callback function
        function nextcellButtonPushed(app, event)
            if app.icell< app.totalCell
                app.icell = app.icell+1;
                
                app.frame = app.pwAll(app.icell,end);
                app.frameLastCell = app.pwAll(app.icell-1,end);
                if app.frame ~= app.frameLastCell
                    imagesc(app.UIAxes2,squeeze(app.tracePhase(:,:,app.frame)));
                    caxis(app.UIAxes2,[-pi,pi]);
                    colormap(app.UIAxes2,hsv);
                    axis(app.UIAxes2, 'tight','equal','on');
                    title(app.UIAxes2, ['frame ' num2str(app.frame) ' /' num2str(app.totalFrame)], 'visible','on');
                end
                
                updateCell(app);
            end
        end

        % Button pushed function: previouscellleftButton
        function previouscellleftButtonPushed(app, event)
             if app.icell>1
                app.icell = app.icell-1;
                
                app.frame = app.pwAll(app.icell,end);
                app.frameLastCell = app.pwAll(app.icell+1,end);
                if app.frame ~= app.frameLastCell
                    imagesc(app.UIAxes2,squeeze(app.tracePhase(:,:,app.frame)));
                    caxis(app.UIAxes2,[-pi,pi]);
                    colormap(app.UIAxes2,hsv);
                    axis(app.UIAxes2, 'tight','equal','on');
                    title(app.UIAxes2, ['frame ' num2str(app.frame) ' /' num2str(app.totalFrame)], 'visible','on');
                end
                
                updateCell(app);
             end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            app.frame = floor(app.Slider.Value);
            indx1 = find(app.pwAll(:,end) == app.frame);
            app.icell = indx1(1);
            imagesc(app.UIAxes,squeeze(app.cells(:,:,app.icell)));
            colormap(app.UIAxes,hsv);
            caxis(app.UIAxes,[-pi,pi])
            axis(app.UIAxes, 'tight','equal','on');
            app.t = title(app.UIAxes, ['cell ',num2str(app.icell)  ' /' num2str(app.totalCell)],'visible','on','Color','k'); 
            app.t.FontSize = 16;
            if app.spiralList(app.icell)
                app.t.Color = 'r';
            end
            
            imagesc(app.UIAxes2,squeeze(app.tracePhase(:,:,app.frame)));
            colormap(app.UIAxes2,hsv);
            caxis(app.UIAxes2,[-pi,pi])
            axis(app.UIAxes2, 'tight','equal','on');
            title(app.UIAxes2, ['frame ' num2str(app.frame) ' /' num2str(app.totalFrame)], 'visible','on');
            
            hold(app.UIAxes2,'on')
            indx = (app.pwAll(:,end) == app.frame);
            points = app.pwAll(indx,:);
            scatter(app.UIAxes2,points(:,1),points(:,2),'w','filled');
            hold(app.UIAxes2,'on')
            scatter(app.UIAxes2,app.pwAll(app.icell,1),app.pwAll(app.icell,2),'k','filled')
            
            %% draw phase value with 2 pixel radius
            [cx,cy,ph,circPhase] = getPhaseValue(app);
            scatter(app.UIAxes3,1:numel(circPhase),circPhase,12,'blue');
            axis(app.UIAxes3, 'tight','on');
            % ylim(app.UIAxes3,[0,2*pi]);
            hold(app.UIAxes3,'on');
            scatter(app.UIAxes3,1:numel(ph),ph,8,'red');
            
            hold(app.UIAxes,'on');
            scatter(app.UIAxes,cx,cy,'w','filled')
        end

        % Button pushed function: spiralaButton
        function spiralaButtonPushed(app, event)
            app.spiralList(app.icell) = 1;
            app.t.Color = 'r';
            pause(0.5)
            nextcellButtonPushed(app, event);
        end

        % Button pushed function: undocButton
        function undocButtonPushed(app, event)
            app.spiralList(app.icell) = 0;
        end

        % Button pushed function: exportListButton
        function exportListButtonPushed(app, event)
            spiralsOut = app.spiralList;
            uisave('spiralsOut','spiralList.mat');
        end

        % Button pushed function: nextcellrightButton
        function UIFigureKeyPress(app, event)

        end

        % Callback function
        function UIFigureKeyPress2(app, event)

        end

        % Key press function: UIFigure
        function UIFigureKeyPress3(app, event)
            app.key = event.Key;           
            switch app.key
                case 'leftarrow'
                    previouscellleftButtonPushed(app, event);
                case 'rightarrow'
                    nextcellButtonPushed(app, event);
                case 'uparrow'
                    lastFrameupButtonPushed(app, event)
                case 'downarrow'
                    nextFramedownButtonPushed(app, event)
                    
                case 'a'
                    spiralaButtonPushed(app, event);
                case 'c'
                    undocButtonPushed(app, event);
            end
        end

        % Button pushed function: loadListButton
        function loadListButtonPushed(app, event)
            [file,path] = uigetfile('*.mat'); %open a mat file
            load(fullfile(path, file));
            app.spiralList = spiralsOut;
        end

        % Button pushed function: nextFramedownButton
        function nextFramedownButtonPushed(app, event)
            if app.frame< app.totalFrame
                app.frame = app.frame+1;
                app.indx = (app.pwAll(:,end)==app.frame);
                while not(sum(app.indx))
                    app.frame = app.frame+1;
                    app.indx = (app.pwAll(:,end)==app.frame);
                end
                updateFrame(app)  
            end            
        end

        % Callback function
        function UIAxes3ButtonDown(app, event)
            
        end

        % Button pushed function: lastFrameupButton
        function lastFrameupButtonPushed(app, event)
            if app.frame >1
                app.frame = app.frame-1;
                app.indx = (app.pwAll(:,end)==app.frame);
                while not(sum(app.indx))
                    app.frame = app.frame-1;
                    app.indx = (app.pwAll(:,end)==app.frame);
                end
                updateFrame(app)  
            end  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress3, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes3 = uiaxes(app.UIFigure);
            app.loadDataButton = uibutton(app.UIFigure, 'push');
            app.loadDataButton.ButtonPushedFcn = createCallbackFcn(app, @loadDataButtonPushed, true);
            app.loadDataButton.Text = 'loadData';
            app.nextcellrightButton = uibutton(app.UIFigure, 'push');
            app.nextcellrightButton.ButtonPushedFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.nextcellrightButton.Text = 'next cell /right';
            app.previouscellleftButton = uibutton(app.UIFigure, 'push');
            app.previouscellleftButton.ButtonPushedFcn = createCallbackFcn(app, @previouscellleftButtonPushed, true);
            app.previouscellleftButton.Text = 'previous cell /left';
            app.spiralaButton = uibutton(app.UIFigure, 'push');
            app.spiralaButton.ButtonPushedFcn = createCallbackFcn(app, @spiralaButtonPushed, true);
            app.spiralaButton.Text = 'spiral /a';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.MajorTicks = [];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.MinorTicks = [];
            app.undocButton = uibutton(app.UIFigure, 'push');
            app.undocButton.ButtonPushedFcn = createCallbackFcn(app, @undocButtonPushed, true);
            app.undocButton.Text = 'undo /c';
            app.exportListButton = uibutton(app.UIFigure, 'push');
            app.exportListButton.ButtonPushedFcn = createCallbackFcn(app, @exportListButtonPushed, true);
            app.exportListButton.Text = 'export List';
            app.loadListButton = uibutton(app.UIFigure, 'push');
            app.loadListButton.ButtonPushedFcn = createCallbackFcn(app, @loadListButtonPushed, true);
            app.loadListButton.Text = 'loadList';
            app.nextFramedownButton = uibutton(app.UIFigure, 'push');
            app.nextFramedownButton.ButtonPushedFcn = createCallbackFcn(app, @nextFramedownButtonPushed, true);
            app.nextFramedownButton.Text = 'nextFrame /down';
            app.lastFrameupButton = uibutton(app.UIFigure, 'push');
            app.lastFrameupButton.ButtonPushedFcn = createCallbackFcn(app, @lastFrameupButtonPushed, true);
            app.lastFrameupButton.Text = 'lastFrame /up';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = manualCurateCellMOD3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end